classdef SEMNano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SEMNanoUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenNewWindowMenu               matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        LensPlotsMenu                   matlab.ui.container.Menu
        LensPSFMenu                     matlab.ui.container.Menu
        LensTranferFunctionMenu         matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutSEMNanoMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        SimulationParametersPanel       matlab.ui.container.Panel
        Permissiblevalves512102420484096Label  matlab.ui.control.Label
        NofpixelsEditField              matlab.ui.control.NumericEditField
        NofpixelsEditFieldLabel         matlab.ui.control.Label
        ProbeParametersPanel            matlab.ui.container.Panel
        mradLabel                       matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        ConvergencehalfanglepEditField  matlab.ui.control.NumericEditField
        ConvergencehalfanglepEditFieldLabel  matlab.ui.control.Label
        ProbecurrentipEditField         matlab.ui.control.NumericEditField
        ProbecurrentipLabel             matlab.ui.control.Label
        LensParametersPanel             matlab.ui.container.Panel
        ParasiticAberrationCoefficinetsofPanel  matlab.ui.container.Panel
        NomenclatureButton              matlab.ui.control.Button
        S7EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_15             matlab.ui.control.Label
        S5EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_14             matlab.ui.control.Label
        A0EditFieldLabel_13             matlab.ui.control.Label
        S3EditField                     matlab.ui.control.NumericEditField
        R7EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_11             matlab.ui.control.Label
        R5EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_10             matlab.ui.control.Label
        G7EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_9              matlab.ui.control.Label
        F6EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_8              matlab.ui.control.Label
        D6EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_7              matlab.ui.control.Label
        A0EditFieldLabel_6              matlab.ui.control.Label
        D4EditField                     matlab.ui.control.NumericEditField
        B6EditField                     matlab.ui.control.NumericEditField
        A0EditField_5Label_7            matlab.ui.control.Label
        A0EditField_5Label_6            matlab.ui.control.Label
        B4EditField                     matlab.ui.control.NumericEditField
        B2EditField                     matlab.ui.control.NumericEditField
        A0EditField_5Label_5            matlab.ui.control.Label
        C7EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_5              matlab.ui.control.Label
        A0EditFieldLabel_4              matlab.ui.control.Label
        C5EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_3              matlab.ui.control.Label
        C3EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel_2              matlab.ui.control.Label
        C1EditField                     matlab.ui.control.NumericEditField
        A7EditField                     matlab.ui.control.NumericEditField
        A0EditField_5Label_4            matlab.ui.control.Label
        A6EditField                     matlab.ui.control.NumericEditField
        A0EditField_5Label_3            matlab.ui.control.Label
        A5EditField                     matlab.ui.control.NumericEditField
        A0EditField_5Label_2            matlab.ui.control.Label
        A4EditField                     matlab.ui.control.NumericEditField
        A4EditFieldLabel                matlab.ui.control.Label
        A3EditField                     matlab.ui.control.NumericEditField
        A3EditFieldLabel                matlab.ui.control.Label
        A2EditField                     matlab.ui.control.NumericEditField
        A2EditFieldLabel                matlab.ui.control.Label
        A1EditField                     matlab.ui.control.NumericEditField
        A1EditFieldLabel                matlab.ui.control.Label
        A0EditField                     matlab.ui.control.NumericEditField
        A0EditFieldLabel                matlab.ui.control.Label
        SeidelAberrationCoefficinetsofPanel  matlab.ui.container.Panel
        rdorderNonaxialAstigmatismw222EditField  matlab.ui.control.NumericEditField
        rdorderNonaxialAstigmatismw222EditFieldLabel  matlab.ui.control.Label
        rdorderNonaxialcomaw131EditField  matlab.ui.control.NumericEditField
        rdorderNonaxialcomaw131EditFieldLabel  matlab.ui.control.Label
        rdorderSphericalaberrationw040EditField  matlab.ui.control.NumericEditField
        rdorderSphericalaberrationw040EditFieldLabel  matlab.ui.control.Label
        storderDefocuswdEditField       matlab.ui.control.NumericEditField
        storderDefocuswdEditFieldLabel  matlab.ui.control.Label
        OffaxispointcoordinatesEditField_3  matlab.ui.control.NumericEditField
        OffaxispointxoyoEditField       matlab.ui.control.NumericEditField
        OffaxispointxoyoEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        FinalApertureDaimeterDEditField  matlab.ui.control.NumericEditField
        FinalApertureDaimeterDEditFieldLabel  matlab.ui.control.Label
        GunParametersPanel              matlab.ui.container.Panel
        nmLabel                         matlab.ui.control.Label
        eVLabel                         matlab.ui.control.Label
        kVLabel                         matlab.ui.control.Label
        Asrm2Label                      matlab.ui.control.Label
        VirtualsourcesizedsEditField    matlab.ui.control.NumericEditField
        VirtualsourcesizedsEditFieldLabel  matlab.ui.control.Label
        AcceleratingVoltageVoEditField  matlab.ui.control.NumericEditField
        AcceleratingVoltageVoEditFieldLabel  matlab.ui.control.Label
        EnergySpreadEEditField          matlab.ui.control.NumericEditField
        EnergySpreadEEditFieldLabel     matlab.ui.control.Label
        GunBrightnessEditField_2        matlab.ui.control.NumericEditField
        GunBrightnessLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RunButton_3                     matlab.ui.control.Button
        InputParametersLabel            matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        OutputParametersLabel           matlab.ui.control.Label
        SimulatedeProbePanel            matlab.ui.container.Panel
        DisplayOptionsLabel             matlab.ui.control.Label
        DSurfaceButton                  matlab.ui.control.Button
        ContourButton                   matlab.ui.control.Button
        XYProfileButton                 matlab.ui.control.Button
        AddPoissonnoisetoprobeCheckBox  matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        CalculatedParametersPanel       matlab.ui.container.Panel
        GeometricalprobewidthdgEditField  matlab.ui.control.NumericEditField
        GeometricalprobewidthdgEditFieldLabel  matlab.ui.control.Label
        LensdemagnificationMEditField   matlab.ui.control.NumericEditField
        LensdemagnificationMEditFieldLabel  matlab.ui.control.Label
        nmLabel_2                       matlab.ui.control.Label
        nmLabel_3                       matlab.ui.control.Label
        CoherentcutofffrequencyfcEditField  matlab.ui.control.NumericEditField
        CoherentcutofffrequencyfcLabel  matlab.ui.control.Label
        LensfnumberfEditField           matlab.ui.control.NumericEditField
        LensfnumberfEditFieldLabel      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        eProbeReconstructionPanel       matlab.ui.container.Panel
        infoButton_2                    matlab.ui.control.Button
        KSlider                         matlab.ui.control.Slider
        KSliderLabel                    matlab.ui.control.Label
        WeinerFilterParameterLabel      matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        SpecimenImagingPanel            matlab.ui.container.Panel
        infoButton                      matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        GaussianNoisenSlider            matlab.ui.control.Slider
        GaussianNoisenSliderLabel       matlab.ui.control.Label
        PathTextArea                    matlab.ui.control.TextArea
        PathLabel                       matlab.ui.control.Label
        SpecimenstructuresignalLabel    matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ImagingProbeReconstructionLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                app.SEMNanoUIFigure.WindowState = 'maximized';
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
            f = msgbox('Calculating...','Progress Info','help');
            Beta_g = app.GunBrightnessEditField_2.Value;
            V_0 = app.AcceleratingVoltageVoEditField.Value;
            delta_E = app.EnergySpreadEEditField.Value;
            d_s = app.VirtualsourcesizedsEditField.Value;
            D = app.FinalApertureDaimeterDEditField.Value;
            W_d = app.storderDefocuswdEditField.Value;
            W_040 = app.rdorderSphericalaberrationw040EditField.Value;
            x_0 = app.OffaxispointcoordinatesEditField_3.Value;
            y_0 = app.OffaxispointxoyoEditField.Value;
            W_131 = app.rdorderNonaxialcomaw131EditField.Value;
            W_222 = app.rdorderNonaxialAstigmatismw222EditField.Value;
            i_p = app.ProbecurrentipEditField.Value;
            alpha_p = app.ConvergencehalfanglepEditField.Value;
            A0  =  app.A0EditField.Value  ; A1  =  app.A1EditField.Value  ;
            C1  =  app.C1EditField.Value  ; A2  =  app.A2EditField.Value  ;
            B2  =  app.B2EditField.Value  ; A3  =  app.A3EditField.Value  ;
            S3  =  app.S3EditField.Value  ; C3  =  app.C3EditField.Value  ;
            A4  =  app.A4EditField.Value  ; B4  =  app.B4EditField.Value  ;
            D4  =  app.D4EditField.Value  ; R5  =  app.R5EditField.Value  ;
            S5  =  app.S5EditField.Value  ; C5  =  app.C5EditField.Value  ;
            A5  =  app.A0EditField.Value  ; B6  =  app.B6EditField.Value  ;
            D6  =  app.D6EditField.Value  ; F6  =  app.F6EditField.Value  ;
            A6  =  app.A6EditField.Value  ; C7  =  app.C7EditField.Value  ;
            S7  =  app.S7EditField.Value  ; R7  =  app.R7EditField.Value  ;
            G7  =  app.G7EditField.Value  ; A7  =  app.A7EditField.Value  ;
            N = app.NofpixelsEditField.Value;
            if N==512 ||  N == 1024  || N == 2048 || N==4096
                [d_g, M , f_num, f_c, probe, psf_n, TF, x,y,fx,fy] = output_parameters(Beta_g, i_p, alpha_p, D, V_0, d_s,x_0, y_0, W_d,W_040,W_131,W_222,...
                    A0,A1,C1,A2,B2,A3,S3,C3,A4,B4,D4,R5,S5,C5,A5,B6,D6,F6,A6,C7,S7,R7,G7,A7,N);
            else
                close(f);
                msgbox('Please enter permissible values of N (# of pixels)','Error','error');
                return
            end
            setappdata(0,'probeimg',probe);
            setappdata(0,'psf_n',psf_n);
            setappdata(0,'TF',TF);
            setappdata(0,'x',x);
            setappdata(0,'y',y);
            setappdata(0,'fx',fx);
            setappdata(0,'fy',fy);
            app.GeometricalprobewidthdgEditField.Value = d_g;
            app.LensdemagnificationMEditField.Value = M;
            app.LensfnumberfEditField.Value = f_num;
            app.CoherentcutofffrequencyfcEditField.Value = f_c;
            close(f);
            imagesc(app.UIAxes,x*1e+9,y*1e+9,probe);
            axis(app.UIAxes,'equal','tight');
            axis(app.UIAxes, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path,~] = uigetfile('*.*');
            filepath = strcat(path,file);
            app.PathTextArea.Value = filepath;
            setappdata(0,'filepath',filepath)
        end

        % Value changed function: AddPoissonnoisetoprobeCheckBox
        function AddPoissonnoisetoprobeCheckBoxValueChanged(app, event)
            probe = getappdata(0,'probeimg');
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            if app.AddPoissonnoisetoprobeCheckBox.Value==1
                noisy_probe = imnoise(probe,'poisson');
                imagesc(app.UIAxes,x*1e+9,y*1e+9,noisy_probe);
                axis(app.UIAxes,'equal','tight');
                axis(app.UIAxes, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
                clear noisy_probe;
            else
                imagesc(app.UIAxes,x*1e+9,y*1e+9,probe);
                axis(app.UIAxes,'equal','tight');
                axis(app.UIAxes, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
                clear probe;
            end
        end

        % Menu selected function: LensPSFMenu
        function LensPSFMenuSelected(app, event)
            psf_n = getappdata(0,'psf_n');
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            display_psf(psf_n,x,y);
        end

        % Menu selected function: LensTranferFunctionMenu
        function LensTranferFunctionMenuSelected(app, event)
            TF = getappdata(0,'TF');
            fx = getappdata(0,'fx');
            fy = getappdata(0,'fy');
            display_TF(TF,fx,fy,app.NofpixelsEditField.Value);
        end

        % Button pushed function: ContourButton
        function ContourButtonPushed(app, event)
            probe = getappdata(0,'probeimg');
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            display_contour(probe,x,y,N);
            clear probe;
        end

        % Button pushed function: XYProfileButton
        function XYProfileButtonPushed(app, event)
            probe = getappdata(0,'probeimg');
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            plot_probe_profile(probe,x,N);
            clear probe;
        end

        % Button pushed function: DSurfaceButton
        function DSurfaceButtonPushed(app, event)
            probe = getappdata(0,'probeimg');
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            display_3Dsurf(probe,x,y,N);
            clear probe;
        end

        % Value changing function: GaussianNoisenSlider
        function GaussianNoisenSliderValueChanging(app, event)
            final_img = getappdata(0,'final_img');
            np = event.Value;
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            noisy_img = add_noise(final_img,np);
            imagesc(app.UIAxes_4,x*1e+9,y*1e+9,noisy_img);colormap(app.UIAxes_4,'gray');
            axis(app.UIAxes_4,'equal','tight');
            axis(app.UIAxes_4, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
            setappdata(0,'noisy_img',noisy_img);
            clear final_img;
        end

        % Value changing function: KSlider
        function KSliderValueChanging(app, event)
            K = event.Value;
            probe = getappdata(0,'probeimg');
            noisy_img = getappdata(0,'noisy_img');
            specimen = getappdata(0,'specimen');
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            imagesc(app.UIAxes_3,x*1e+9,y*1e+9,probe);
            axis(app.UIAxes_3,'equal','tight');
            axis(app.UIAxes_3, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
            noisy_img_fft = myfft(noisy_img);                     % Spectrum of noisy image
            specimen_fft = myfft(specimen);
            recons_probe = wiener_filter(specimen_fft,noisy_img_fft,K); % Wiener filter function
            imagesc(app.UIAxes_5,x*1e+9,y*1e+9,recons_probe);
            axis(app.UIAxes_5,'equal','tight');
            axis(app.UIAxes_5, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            filepath = getappdata(0,'filepath');
            if filepath == ""
                msgbox('Please load an image file first!','Error','error');
                return
            end
            
            N = app.NofpixelsEditField.Value;
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            probe = getappdata(0,'probeimg');
            
            specimen = imread(filepath);
            specimen = (rgb2gray(specimen));
            specimen = im2double(specimen/max(specimen(:)));
            specimen = imresize(specimen,[N N]);
            
            imagesc(app.UIAxes_2,x*1e+9,y*1e+9,specimen);
            axis(app.UIAxes_2,'equal','tight');colormap(app.UIAxes_2,'gray');
            axis(app.UIAxes_2, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
            
            final_img = imaging_specimen(specimen,probe);
            imagesc(app.UIAxes_4,x*1e+9,y*1e+9,final_img);colormap(app.UIAxes_4,'gray');
            axis(app.UIAxes_4,'equal','tight');
            axis(app.UIAxes_4, [ x(1)*1e+9, x(N)*1e+9, y(1)*1e+9, y(N)*1e+9]);
            
            setappdata(0,'final_img',final_img);
            setappdata(0,'specimen',specimen);
            clear filepath;
        end

        % Button pushed function: NomenclatureButton
        function NomenclatureButtonPushed(app, event)
            msgbox({'A0 : Image shift';['' ...
                'A1 : Two-fold axial astigmatism (or axial astigmatism of the 1st order)'];[''...
                'A2 : Three-fold axial astigmatism (or axial astigmatism of the 2nd order)'];[''...
                'A3 : Four-fold axial astigmatism or axial astigmatism of the 3rd order Cs'];[''...
                'A4 : Five-fold axial astigmatism or axial astigmatism of the 4th order'];[''...
                'A5 : Six-fold axial astigmatism or sixfold axial astigmatism of the 5th order'];[''...
                'A6 : Sevenfold astigmatism'];[''...
                'A7 : Eightfold astigmatism'];[''...
                'B2 : Second-order axial coma'];[''...
                'B4 : Fourth-order axial coma'];[''...
                'B6 : Sixth order axial coma'];[''...
                'C1 : Defocus or spherical aberration of the 1st order'];[''...
                'C3 : Third-order spherical aberration (Cs)'];[''...
                'C5 : Fifth-order spherical aberration'];[''...
                'C7 : Seventh-order spherical aberration'];[''...
                'D4 : Fourth order threefold astigmatism (or Three lobe aberration)'];[''...
                'D6 : Sixth order three-lobe aberration'];[''...
                'F6 : Sixth order pentacle aberration'];[''...
                'G7 : Seventh-order chaplet aberration'];[''...
                'R5 : Fourfold astigmatism of C5 (or Fifth order rosette aberration)'];[''...
                'R7 : Seventh-order rosette aberration'];[''...
                'S3 : Twofold astigmatism of Cs (or Third order twofold astigmatism, or Axial star aberration of the 3rd order)'];[''...
                'S5 : Twofold astigmatism of C5 (or Fifth-order axial star aberration)'];[''...
                'S7 : Seventh-order star aberration']},'Parasitic Aberrations');
        end

        % Menu selected function: AboutSEMNanoMenu
        function AboutSEMNanoMenuSelected(app, event)
            msgbox({'SEM Nano Version 1.0 , Copyright (c) 2022 Surya Kamal ';[''...
                ''];[''...
                'Author: Surya Kamal , e-mail: surya@mail.rit.edu'];[''...
                ''];[''...
                ''];[''...
                'SEM Nano is an open source software developed in the NanoImaging Lab at the' ...
                ' Rochester Institute of Technology, NY, USA. This is an open source software and is freely' ...
                'available for use under the MIT License of the open source initiative.'];[''...
                ''];[''...
                ''];[''...
                'If you use our software in your research to generate results, verification of results or in any kind of analysis' ...
                ' that leads to a publication, please state the use of SEM Nano in your publication and cite us.'];[''...
                ''];[''...
                'Reference:'];[''...
                'S. Kamal & R. Hailstone, SEM Nano: An Electron Wave Optical Simulation for the Scanning Electron Microscope, Microsc Microanal. doi:10.1017/S1431927622000198']},'SEM Nano Version 1.0')
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            close(app.SEMNanoUIFigure);
            SEMNano;
        end

        % Menu selected function: OpenNewWindowMenu
        function OpenNewWindowMenuSelected(app, event)
            SEMNano;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.SEMNanoUIFigure);
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            msgbox({' A random value between [-n,n] is generated from a normal distribution and added to each image pixel';[''...
                ''];},'Gaussian Noise','help');
        end

        % Button pushed function: infoButton_2
        function infoButton_2Pushed(app, event)
            msgbox({'Final image    =     ( True Image   *   psf  )    +     noise  ....    (1)  ';['' ...
                ''];[''...
                'Taking Fourier Transform both sides'];[''...
                ''];[''...
                'I   =   ( OTF . T )  +   N             .......    (2)'];[''...
                ''];[''...
                'Rearranging and taking inverse Fourier transform both sides'];[''...
                ''];[''...
                'psf_reconstruced    =    (T_   /  ( |T|^2  +   K ))  .  I     ....   (3)' ...
                ''];[''...
                ''];[''...
                ' where '];[''...
                ''];[''...
                '* is convolution'];[''...
                ''];[''...
                'T_  is  complex conjugate  of T ' ...
                ''];[''...
                ''];[''...
                'K is the ratio of noise power to OTF power '];},'Wiener Deconvolution','help');
        end

        % Window key press function: SEMNanoUIFigure
        function SEMNanoUIFigureWindowKeyPress(app, event)
                drawnow;
                app.SEMNanoUIFigure.WindowState = 'maximized';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SEMNanoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {753, 753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {659, '1x', 463};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SEMNanoUIFigure = uifigure('Visible', 'off');
            app.SEMNanoUIFigure.Name = 'SEM Nano';
            app.SEMNanoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SEMNanoUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @SEMNanoUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.SEMNanoUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenNewWindowMenu = uimenu(app.FileMenu);
            app.OpenNewWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenNewWindowMenuSelected, true);
            app.OpenNewWindowMenu.Text = 'Open New Window';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.LensPlotsMenu = uimenu(app.SEMNanoUIFigure);
            app.LensPlotsMenu.Text = 'Lens Plots';
            app.LensPSFMenu = uimenu(app.LensPlotsMenu);
            app.LensPSFMenu.MenuSelectedFcn = createCallbackFcn(app, @LensPSFMenuSelected, true);
            app.LensPSFMenu.Text = 'Lens PSF';
            app.LensTranferFunctionMenu = uimenu(app.LensPlotsMenu);
            app.LensTranferFunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @LensTranferFunctionMenuSelected, true);
            app.LensTranferFunctionMenu.Text = 'Lens Tranfer Function';
            app.HelpMenu = uimenu(app.SEMNanoUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutSEMNanoMenu = uimenu(app.HelpMenu);
            app.AboutSEMNanoMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSEMNanoMenuSelected, true);
            app.AboutSEMNanoMenu.Text = 'About SEM Nano';
            app.GridLayout = uigridlayout(app.SEMNanoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputParametersLabel = uilabel(app.LeftPanel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.RunButton_3 = uibutton(app.LeftPanel, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.GunParametersPanel = uipanel(app.LeftPanel);
            app.GunParametersPanel.Title = '             Gun Parameters';
            app.Label = uilabel(app.GunParametersPanel);
            app.Label.Text = '';
            app.GunBrightnessLabel = uilabel(app.GunParametersPanel);
            app.GunBrightnessLabel.Text = 'Gun Brightness - β';
            app.GunBrightnessEditField_2 = uieditfield(app.GunParametersPanel, 'numeric');
            app.GunBrightnessEditField_2.Limits = [0 Inf];
            app.GunBrightnessEditField_2.Value = 1000000000000;
            app.EnergySpreadEEditFieldLabel = uilabel(app.GunParametersPanel);
            app.EnergySpreadEEditFieldLabel.Text = 'Energy Spread - ΔE';
            app.EnergySpreadEEditField = uieditfield(app.GunParametersPanel, 'numeric');
            app.EnergySpreadEEditField.Limits = [0 0.7];
            app.AcceleratingVoltageVoEditFieldLabel = uilabel(app.GunParametersPanel);
            app.AcceleratingVoltageVoEditFieldLabel.Text = 'Accelerating Voltage - Vo';
            app.AcceleratingVoltageVoEditField = uieditfield(app.GunParametersPanel, 'numeric');
            app.AcceleratingVoltageVoEditField.Limits = [0.1 30];
            app.AcceleratingVoltageVoEditField.Value = 5;
            app.VirtualsourcesizedsEditFieldLabel = uilabel(app.GunParametersPanel);
            app.VirtualsourcesizedsEditFieldLabel.Text = 'Virtual source size - ds';
            app.VirtualsourcesizedsEditField = uieditfield(app.GunParametersPanel, 'numeric');
            app.VirtualsourcesizedsEditField.Limits = [3 30];
            app.VirtualsourcesizedsEditField.Value = 20;
            app.Asrm2Label = uilabel(app.GunParametersPanel);
            app.Asrm2Label.Text = 'A.sr/m^2';
            app.kVLabel = uilabel(app.GunParametersPanel);
            app.kVLabel.Text = 'kV';
            app.eVLabel = uilabel(app.GunParametersPanel);
            app.eVLabel.Text = 'eV';
            app.nmLabel = uilabel(app.GunParametersPanel);
            app.nmLabel.Text = 'nm';
            app.LensParametersPanel = uipanel(app.LeftPanel);
            app.LensParametersPanel.Title = '                                                Lens Parameters';
            app.FinalApertureDaimeterDEditFieldLabel = uilabel(app.LensParametersPanel);
            app.FinalApertureDaimeterDEditFieldLabel.Text = 'Final Aperture Daimeter - D';
            app.FinalApertureDaimeterDEditField = uieditfield(app.LensParametersPanel, 'numeric');
            app.FinalApertureDaimeterDEditField.Limits = [5 60];
            app.FinalApertureDaimeterDEditField.Value = 50;
            app.mLabel = uilabel(app.LensParametersPanel);
            app.mLabel.Text = 'µm';
            app.Label_4 = uilabel(app.LensParametersPanel);
            app.Label_4.Text = '';
            app.SeidelAberrationCoefficinetsofPanel = uipanel(app.LensParametersPanel);
            app.SeidelAberrationCoefficinetsofPanel.Title = 'Seidel Aberration Coefficinets (# of λ)';
            app.OffaxispointxoyoEditFieldLabel = uilabel(app.SeidelAberrationCoefficinetsofPanel);
            app.OffaxispointxoyoEditFieldLabel.Text = 'Off-axis point  (xo,yo)';
            app.OffaxispointxoyoEditField = uieditfield(app.SeidelAberrationCoefficinetsofPanel, 'numeric');
            app.OffaxispointxoyoEditField.Limits = [-10 10];
            app.OffaxispointxoyoEditField.Value = -1;
            app.OffaxispointcoordinatesEditField_3 = uieditfield(app.SeidelAberrationCoefficinetsofPanel, 'numeric');
            app.OffaxispointcoordinatesEditField_3.Limits = [-10 10];
            app.OffaxispointcoordinatesEditField_3.Value = 1;
            app.storderDefocuswdEditFieldLabel = uilabel(app.SeidelAberrationCoefficinetsofPanel);
            app.storderDefocuswdEditFieldLabel.Text = '1st order Defocus (wd)';
            app.storderDefocuswdEditField = uieditfield(app.SeidelAberrationCoefficinetsofPanel, 'numeric');
            app.storderDefocuswdEditField.Limits = [-50 50];
            app.rdorderSphericalaberrationw040EditFieldLabel = uilabel(app.SeidelAberrationCoefficinetsofPanel);
            app.rdorderSphericalaberrationw040EditFieldLabel.Text = '3rd order Spherical aberration (w040)';
            app.rdorderSphericalaberrationw040EditField = uieditfield(app.SeidelAberrationCoefficinetsofPanel, 'numeric');
            app.rdorderSphericalaberrationw040EditField.Limits = [-50 50];
            app.rdorderNonaxialcomaw131EditFieldLabel = uilabel(app.SeidelAberrationCoefficinetsofPanel);
            app.rdorderNonaxialcomaw131EditFieldLabel.Text = '3rd order Non-axial coma (w131)';
            app.rdorderNonaxialcomaw131EditField = uieditfield(app.SeidelAberrationCoefficinetsofPanel, 'numeric');
            app.rdorderNonaxialcomaw131EditField.Limits = [-50 50];
            app.rdorderNonaxialAstigmatismw222EditFieldLabel = uilabel(app.SeidelAberrationCoefficinetsofPanel);
            app.rdorderNonaxialAstigmatismw222EditFieldLabel.Text = '3rd order Non-axial Astigmatism (w222)';
            app.rdorderNonaxialAstigmatismw222EditField = uieditfield(app.SeidelAberrationCoefficinetsofPanel, 'numeric');
            app.rdorderNonaxialAstigmatismw222EditField.Limits = [-50 50];
            app.ParasiticAberrationCoefficinetsofPanel = uipanel(app.LensParametersPanel);
            app.ParasiticAberrationCoefficinetsofPanel.Title = 'Parasitic Aberration Coefficinets ((# of λ)';
            app.A0EditFieldLabel = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel.Text = 'A0';
            app.A0EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A0EditField.Limits = [-50 50];
            app.A1EditFieldLabel = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A1EditField.Limits = [-50 50];
            app.A2EditFieldLabel = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A2EditField.Limits = [-50 50];
            app.A3EditFieldLabel = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A3EditFieldLabel.Text = 'A3';
            app.A3EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A3EditField.Limits = [-50 50];
            app.A4EditFieldLabel = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A4EditFieldLabel.Text = 'A4';
            app.A4EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A4EditField.Limits = [-50 50];
            app.A0EditField_5Label_2 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditField_5Label_2.Text = 'A5';
            app.A5EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A5EditField.Limits = [-50 50];
            app.A0EditField_5Label_3 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditField_5Label_3.Text = 'A6';
            app.A6EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A6EditField.Limits = [-50 50];
            app.A0EditField_5Label_4 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditField_5Label_4.Text = 'A7';
            app.A7EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.A7EditField.Limits = [-50 50];
            app.C1EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.C1EditField.Limits = [-50 50];
            app.A0EditFieldLabel_2 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_2.Text = 'C1';
            app.C3EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.C3EditField.Limits = [-50 50];
            app.A0EditFieldLabel_3 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_3.Text = 'C3';
            app.C5EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.C5EditField.Limits = [-50 50];
            app.A0EditFieldLabel_4 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_4.Text = 'C5';
            app.A0EditFieldLabel_5 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_5.Text = 'C7';
            app.C7EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.C7EditField.Limits = [-50 50];
            app.A0EditField_5Label_5 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditField_5Label_5.Text = 'B2';
            app.B2EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.B2EditField.Limits = [-50 50];
            app.B4EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.B4EditField.Limits = [-50 50];
            app.A0EditField_5Label_6 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditField_5Label_6.Text = 'B4';
            app.A0EditField_5Label_7 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditField_5Label_7.Text = 'B6';
            app.B6EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.B6EditField.Limits = [-50 50];
            app.D4EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.D4EditField.Limits = [-50 50];
            app.A0EditFieldLabel_6 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_6.Text = 'D4';
            app.A0EditFieldLabel_7 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_7.Text = 'D6';
            app.D6EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.D6EditField.Limits = [-50 50];
            app.A0EditFieldLabel_8 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_8.Text = 'F6';
            app.F6EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.F6EditField.Limits = [-50 50];
            app.A0EditFieldLabel_9 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_9.Text = 'G7';
            app.G7EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.G7EditField.Limits = [-50 50];
            app.A0EditFieldLabel_10 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_10.Text = 'R5';
            app.R5EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.R5EditField.Limits = [-50 50];
            app.A0EditFieldLabel_11 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_11.Text = 'R7';
            app.R7EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.R7EditField.Limits = [-50 50];
            app.S3EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.S3EditField.Limits = [-50 50];
            app.A0EditFieldLabel_13 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_13.Text = 'S3';
            app.A0EditFieldLabel_14 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_14.Text = 'S5';
            app.S5EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.S5EditField.Limits = [-50 50];
            app.A0EditFieldLabel_15 = uilabel(app.ParasiticAberrationCoefficinetsofPanel);
            app.A0EditFieldLabel_15.Text = 'S7';
            app.S7EditField = uieditfield(app.ParasiticAberrationCoefficinetsofPanel, 'numeric');
            app.S7EditField.Limits = [-50 50];
            app.NomenclatureButton = uibutton(app.ParasiticAberrationCoefficinetsofPanel, 'push');
            app.NomenclatureButton.ButtonPushedFcn = createCallbackFcn(app, @NomenclatureButtonPushed, true);
            app.NomenclatureButton.Text = 'Nomenclature';
            app.ProbeParametersPanel = uipanel(app.LeftPanel);
            app.ProbeParametersPanel.Title = '             Probe Parameters';
            app.ProbecurrentipLabel = uilabel(app.ProbeParametersPanel);
            app.ProbecurrentipLabel.Text = 'Probe current - ip';
            app.ProbecurrentipEditField = uieditfield(app.ProbeParametersPanel, 'numeric');
            app.ProbecurrentipEditField.Limits = [0 1e-07];
            app.ProbecurrentipEditField.Value = 1e-10;
            app.ConvergencehalfanglepEditFieldLabel = uilabel(app.ProbeParametersPanel);
            app.ConvergencehalfanglepEditFieldLabel.Text = 'Convergence half-angle - αp';
            app.ConvergencehalfanglepEditField = uieditfield(app.ProbeParametersPanel, 'numeric');
            app.ConvergencehalfanglepEditField.Limits = [0 50];
            app.ConvergencehalfanglepEditField.Value = 5;
            app.ALabel = uilabel(app.ProbeParametersPanel);
            app.ALabel.Text = 'A';
            app.mradLabel = uilabel(app.ProbeParametersPanel);
            app.mradLabel.Text = 'mrad';
            app.SimulationParametersPanel = uipanel(app.LeftPanel);
            app.SimulationParametersPanel.Title = '            Simulation Parameters';
            app.NofpixelsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.NofpixelsEditFieldLabel.Text = 'N - # of pixels';
            app.NofpixelsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.NofpixelsEditField.Limits = [512 4096];
            app.NofpixelsEditField.RoundFractionalValues = 'on';
            app.NofpixelsEditField.Value = 512;
            app.Permissiblevalves512102420484096Label = uilabel(app.SimulationParametersPanel);
            app.Permissiblevalves512102420484096Label.Text = 'Permissible valves: (512, 1024, 2048, 4096)';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Label_3 = uilabel(app.CenterPanel);
            app.Label_3.Text = '';
            app.CalculatedParametersPanel = uipanel(app.CenterPanel);
            app.CalculatedParametersPanel.Title = '           Calculated Parameters';
            app.Label_2 = uilabel(app.CalculatedParametersPanel);
            app.Label_2.Text = '';
            app.LensfnumberfEditFieldLabel = uilabel(app.CalculatedParametersPanel);
            app.LensfnumberfEditFieldLabel.Text = 'Lens f-number - f # ';
            app.LensfnumberfEditField = uieditfield(app.CalculatedParametersPanel, 'numeric');
            app.CoherentcutofffrequencyfcLabel = uilabel(app.CalculatedParametersPanel);
            app.CoherentcutofffrequencyfcLabel.Text = 'Coherent cut-off frequency - fc';
            app.CoherentcutofffrequencyfcEditField = uieditfield(app.CalculatedParametersPanel, 'numeric');
            app.nmLabel_3 = uilabel(app.CalculatedParametersPanel);
            app.nmLabel_3.Text = 'nm';
            app.nmLabel_2 = uilabel(app.CalculatedParametersPanel);
            app.nmLabel_2.Text = '1/nm';
            app.LensdemagnificationMEditFieldLabel = uilabel(app.CalculatedParametersPanel);
            app.LensdemagnificationMEditFieldLabel.Text = 'Lens demagnification - M';
            app.LensdemagnificationMEditField = uieditfield(app.CalculatedParametersPanel, 'numeric');
            app.GeometricalprobewidthdgEditFieldLabel = uilabel(app.CalculatedParametersPanel);
            app.GeometricalprobewidthdgEditFieldLabel.Text = 'Geometrical probe width - dg';
            app.GeometricalprobewidthdgEditField = uieditfield(app.CalculatedParametersPanel, 'numeric');
            app.GeometricalprobewidthdgEditField.Limits = [0 Inf];
            app.SimulatedeProbePanel = uipanel(app.CenterPanel);
            app.SimulatedeProbePanel.Title = '              Simulated e- Probe';
            app.UIAxes = uiaxes(app.SimulatedeProbePanel);
            title(app.UIAxes, 'e- probe at the specimen')
            xlabel(app.UIAxes, 'nm')
            ylabel(app.UIAxes, 'nm')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.AddPoissonnoisetoprobeCheckBox = uicheckbox(app.SimulatedeProbePanel);
            app.AddPoissonnoisetoprobeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddPoissonnoisetoprobeCheckBoxValueChanged, true);
            app.AddPoissonnoisetoprobeCheckBox.Text = 'Add Poisson noise to probe';
            app.XYProfileButton = uibutton(app.SimulatedeProbePanel, 'push');
            app.XYProfileButton.ButtonPushedFcn = createCallbackFcn(app, @XYProfileButtonPushed, true);
            app.XYProfileButton.Text = 'XY Profile';
            app.ContourButton = uibutton(app.SimulatedeProbePanel, 'push');
            app.ContourButton.ButtonPushedFcn = createCallbackFcn(app, @ContourButtonPushed, true);
            app.ContourButton.Text = 'Contour';
            app.DSurfaceButton = uibutton(app.SimulatedeProbePanel, 'push');
            app.DSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @DSurfaceButtonPushed, true);
            app.DSurfaceButton.Text = '3D Surface';
            app.DisplayOptionsLabel = uilabel(app.SimulatedeProbePanel);
            app.DisplayOptionsLabel.Text = 'Display Options';
            app.OutputParametersLabel = uilabel(app.CenterPanel);
            app.OutputParametersLabel.Text = 'Output Parameters';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImagingProbeReconstructionLabel = uilabel(app.RightPanel);
            app.ImagingProbeReconstructionLabel.Text = 'Imaging & Probe Reconstruction';
            app.SpecimenImagingPanel = uipanel(app.RightPanel);
            app.SpecimenImagingPanel.Title = '                           Specimen Imaging';
            app.UIAxes_2 = uiaxes(app.SpecimenImagingPanel);
            title(app.UIAxes_2, 'Structure signal')
            xlabel(app.UIAxes_2, 'nm')
            ylabel(app.UIAxes_2, 'nm')
            app.UIAxes_4 = uiaxes(app.SpecimenImagingPanel);
            title(app.UIAxes_4, 'Final Image')
            xlabel(app.UIAxes_4, 'nm')
            ylabel(app.UIAxes_4, 'nm')
            app.LoadButton = uibutton(app.SpecimenImagingPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SpecimenstructuresignalLabel = uilabel(app.SpecimenImagingPanel);
            app.SpecimenstructuresignalLabel.Text = 'Specimen structure signal :';
            app.PathLabel = uilabel(app.SpecimenImagingPanel);
            app.PathLabel.Text = 'Path :';
            app.PathTextArea = uitextarea(app.SpecimenImagingPanel);
            app.GaussianNoisenSliderLabel = uilabel(app.SpecimenImagingPanel);
            app.GaussianNoisenSliderLabel.Text = 'Gaussian Noise (n)';
            app.GaussianNoisenSlider = uislider(app.SpecimenImagingPanel);
            app.GaussianNoisenSlider.Limits = [0 1];
            app.GaussianNoisenSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianNoisenSliderValueChanging, true);
            app.RunButton_2 = uibutton(app.SpecimenImagingPanel, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.infoButton = uibutton(app.SpecimenImagingPanel, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Text = 'info';
            app.eProbeReconstructionPanel = uipanel(app.RightPanel);
            app.eProbeReconstructionPanel.Title = '                        e- Probe Reconstruction';
            app.UIAxes_3 = uiaxes(app.eProbeReconstructionPanel);
            title(app.UIAxes_3, 'Input probe')
            xlabel(app.UIAxes_3, 'nm')
            ylabel(app.UIAxes_3, 'nm')
            app.UIAxes_5 = uiaxes(app.eProbeReconstructionPanel);
            title(app.UIAxes_5, 'Reconstructed probe')
            xlabel(app.UIAxes_5, 'nm')
            ylabel(app.UIAxes_5, 'nm')
            app.WeinerFilterParameterLabel = uilabel(app.eProbeReconstructionPanel);
            app.WeinerFilterParameterLabel.Text = '(Weiner Filter Parameter)';
            app.KSliderLabel = uilabel(app.eProbeReconstructionPanel);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.eProbeReconstructionPanel);
            app.KSlider.Limits = [0 10000];
            app.KSlider.ValueChangingFcn = createCallbackFcn(app, @KSliderValueChanging, true);
            app.infoButton_2 = uibutton(app.eProbeReconstructionPanel, 'push');
            app.infoButton_2.ButtonPushedFcn = createCallbackFcn(app, @infoButton_2Pushed, true);
            app.infoButton_2.Text = 'info';
            % Show the figure after all components are created
            app.SEMNanoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEMNano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SEMNanoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SEMNanoUIFigure)
        end
    end
end